classdef dset_viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SmoothsigmamsEditField       matlab.ui.control.NumericEditField
        SmoothsigmamsEditFieldLabel  matlab.ui.control.Label
        SmoothCheckBox               matlab.ui.control.CheckBox
        DeconvolutionmethodDropDown  matlab.ui.control.DropDown
        DeconvolutionmethodDropDownLabel  matlab.ui.control.Label
        MMNfreqEditField             matlab.ui.control.EditField
        MMNfreqEditFieldLabel        matlab.ui.control.Label
        FullredCheckBox              matlab.ui.control.CheckBox
        PlotctxrespButton            matlab.ui.control.Button
        PlotfreqrespButton           matlab.ui.control.Button
        CButton                      matlab.ui.control.StateButton
        UpdateButton                 matlab.ui.control.Button
        ShiftEditField_st            matlab.ui.control.NumericEditField
        ShiftEditField_3Label        matlab.ui.control.Label
        ScaleEditField_st            matlab.ui.control.NumericEditField
        ScaleEditField_3Label        matlab.ui.control.Label
        ShiftEditField_sp            matlab.ui.control.NumericEditField
        ShiftEditFieldLabel          matlab.ui.control.Label
        ScaleEditField_sp            matlab.ui.control.NumericEditField
        ScaleEditFieldLabel          matlab.ui.control.Label
        trialtypeDropDown            matlab.ui.control.DropDown
        trialtypeDropDownLabel       matlab.ui.control.Label
        StimtimesButton              matlab.ui.control.StateButton
        SNREditField                 matlab.ui.control.NumericEditField
        SNREditFieldLabel            matlab.ui.control.Label
        LoadmatdataButton            matlab.ui.control.Button
        s31datapathEditField         matlab.ui.control.EditField
        s31datapathEditFieldLabel    matlab.ui.control.Label
        SpikesButton                 matlab.ui.control.StateButton
        RawButton                    matlab.ui.control.StateButton
        CellSpinner                  matlab.ui.control.Spinner
        CellSpinnerLabel             matlab.ui.control.Label
        mplSpinner                   matlab.ui.control.Spinner
        mplSpinnerLabel              matlab.ui.control.Label
        Button                       matlab.ui.control.Button
        DatasetDropDown              matlab.ui.control.DropDown
        DatasetDropDownLabel         matlab.ui.control.Label
        Runs31Button                 matlab.ui.control.Button
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end


    properties (Access = public)
        gui_ops= struct;
        data;
        ops;
        ddata;
        A_image;
        A_contour;
        plot_C;
        plot_raw;
        plot_spikes;
        plot_stim_times;
        plot_current_contour;
        current_cell_raw;
        current_cell_C;
        current_cell_spikes;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.gui_ops.gui_dir = fileparts(which('dset_viewer.mlapp'));
            addpath(app.gui_ops.gui_dir);
            addpath([app.gui_ops.gui_dir '\gui_functions']);
            addpath([app.gui_ops.gui_dir '\general_functions']);
            addpath([app.gui_ops.gui_dir '\s3_mpl_functions']);
            f_dv_initialize(app);
        end

        % Button pushed function: Runs31Button
        function Runs31ButtonPushed(app, event)
            f_dv_runs31_buttion(app);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            f_dv_random_button(app);
        end

        % Value changed function: DatasetDropDown
        function DatasetDropDownValueChanged(app, event)
            f_dv_load_dset_from_data(app);
        end

        % Value changed function: mplSpinner
        function mplSpinnerValueChanged(app, event)
            f_dv_update_dset_info(app);
        end

        % Value changed function: CellSpinner
        function CellSpinnerValueChanged(app, event)
            f_dv_update_cell(app);
        end

        % Button pushed function: LoadmatdataButton
        function LoadmatdataButtonPushed(app, event)
            f_dv_load_data_mat(app);
        end

        % Value changed function: RawButton
        function RawButtonValueChanged(app, event)
            f_dv_update_cell(app);
        end

        % Value changed function: CButton
        function CButtonValueChanged(app, event)
            f_dv_update_cell(app);
        end

        % Value changed function: SpikesButton
        function SpikesButtonValueChanged(app, event)
            f_dv_update_cell(app);
        end

        % Value changed function: StimtimesButton
        function StimtimesButtonValueChanged(app, event)
            f_dv_update_cell(app);
        end

        % Value changed function: trialtypeDropDown
        function trialtypeDropDownValueChanged(app, event)
            f_dv_update_cell(app);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            f_dv_update_cell(app);
        end

        % Button pushed function: PlotfreqrespButton
        function PlotfreqrespButtonPushed(app, event)
            f_dv_plot_freq_resp(app);
        end

        % Button pushed function: PlotctxrespButton
        function PlotctxrespButtonPushed(app, event)
            f_dv_plot_ctx_resp(app);
        end

        % Value changed function: SmoothCheckBox
        function SmoothCheckBoxValueChanged(app, event)
            f_dv_update_cell(app);
        end

        % Value changed function: DeconvolutionmethodDropDown
        function DeconvolutionmethodDropDownValueChanged(app, event)
            f_dv_update_cell(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'A')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Trace')
            xlabel(app.UIAxes2, 'Time (sec)')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Runs31Button = uibutton(app.UIFigure, 'push');
            app.Runs31Button.ButtonPushedFcn = createCallbackFcn(app, @Runs31ButtonPushed, true);
            app.Runs31Button.Text = 'Run s31';
            app.DatasetDropDownLabel = uilabel(app.UIFigure);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.UIFigure);
            app.DatasetDropDown.Items = {};
            app.DatasetDropDown.ValueChangedFcn = createCallbackFcn(app, @DatasetDropDownValueChanged, true);
            app.DatasetDropDown.Value = {};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.mplSpinnerLabel = uilabel(app.UIFigure);
            app.mplSpinnerLabel.Text = 'mpl';
            app.mplSpinner = uispinner(app.UIFigure);
            app.mplSpinner.Limits = [1 Inf];
            app.mplSpinner.RoundFractionalValues = 'on';
            app.mplSpinner.ValueChangedFcn = createCallbackFcn(app, @mplSpinnerValueChanged, true);
            app.mplSpinner.Value = 1;
            app.CellSpinnerLabel = uilabel(app.UIFigure);
            app.CellSpinnerLabel.Text = 'Cell';
            app.CellSpinner = uispinner(app.UIFigure);
            app.CellSpinner.Limits = [1 Inf];
            app.CellSpinner.RoundFractionalValues = 'on';
            app.CellSpinner.ValueChangedFcn = createCallbackFcn(app, @CellSpinnerValueChanged, true);
            app.CellSpinner.Value = 1;
            app.RawButton = uibutton(app.UIFigure, 'state');
            app.RawButton.ValueChangedFcn = createCallbackFcn(app, @RawButtonValueChanged, true);
            app.RawButton.Text = 'Raw';
            app.RawButton.Value = true;
            app.SpikesButton = uibutton(app.UIFigure, 'state');
            app.SpikesButton.ValueChangedFcn = createCallbackFcn(app, @SpikesButtonValueChanged, true);
            app.SpikesButton.Text = 'Spikes';
            app.s31datapathEditFieldLabel = uilabel(app.UIFigure);
            app.s31datapathEditFieldLabel.Text = 's31 data path';
            app.s31datapathEditField = uieditfield(app.UIFigure, 'text');
            app.s31datapathEditField.Value = 'C:\Users\ys2605\Desktop\stuff\AC_2p_analysis\s31_data_mpl_5_3_21.mat';
            app.LoadmatdataButton = uibutton(app.UIFigure, 'push');
            app.LoadmatdataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmatdataButtonPushed, true);
            app.LoadmatdataButton.Text = 'Load mat data';
            app.SNREditFieldLabel = uilabel(app.UIFigure);
            app.SNREditFieldLabel.Text = 'SNR';
            app.SNREditField = uieditfield(app.UIFigure, 'numeric');
            app.SNREditField.Editable = 'off';
            app.StimtimesButton = uibutton(app.UIFigure, 'state');
            app.StimtimesButton.ValueChangedFcn = createCallbackFcn(app, @StimtimesButtonValueChanged, true);
            app.StimtimesButton.Text = 'Stim times';
            app.trialtypeDropDownLabel = uilabel(app.UIFigure);
            app.trialtypeDropDownLabel.Text = 'trial type';
            app.trialtypeDropDown = uidropdown(app.UIFigure);
            app.trialtypeDropDown.Items = {};
            app.trialtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @trialtypeDropDownValueChanged, true);
            app.trialtypeDropDown.Value = {};
            app.ScaleEditFieldLabel = uilabel(app.UIFigure);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField_sp = uieditfield(app.UIFigure, 'numeric');
            app.ScaleEditField_sp.Value = 1;
            app.ShiftEditFieldLabel = uilabel(app.UIFigure);
            app.ShiftEditFieldLabel.Text = 'Shift';
            app.ShiftEditField_sp = uieditfield(app.UIFigure, 'numeric');
            app.ScaleEditField_3Label = uilabel(app.UIFigure);
            app.ScaleEditField_3Label.Text = 'Scale';
            app.ScaleEditField_st = uieditfield(app.UIFigure, 'numeric');
            app.ScaleEditField_st.Value = 1;
            app.ShiftEditField_3Label = uilabel(app.UIFigure);
            app.ShiftEditField_3Label.Text = 'Shift';
            app.ShiftEditField_st = uieditfield(app.UIFigure, 'numeric');
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.CButton = uibutton(app.UIFigure, 'state');
            app.CButton.ValueChangedFcn = createCallbackFcn(app, @CButtonValueChanged, true);
            app.CButton.Text = 'C';
            app.PlotfreqrespButton = uibutton(app.UIFigure, 'push');
            app.PlotfreqrespButton.ButtonPushedFcn = createCallbackFcn(app, @PlotfreqrespButtonPushed, true);
            app.PlotfreqrespButton.Text = 'Plot freq resp';
            app.PlotctxrespButton = uibutton(app.UIFigure, 'push');
            app.PlotctxrespButton.ButtonPushedFcn = createCallbackFcn(app, @PlotctxrespButtonPushed, true);
            app.PlotctxrespButton.Text = 'Plot ctx resp';
            app.FullredCheckBox = uicheckbox(app.UIFigure);
            app.FullredCheckBox.Text = 'Full red';
            app.MMNfreqEditFieldLabel = uilabel(app.UIFigure);
            app.MMNfreqEditFieldLabel.Text = 'MMN freq';
            app.MMNfreqEditField = uieditfield(app.UIFigure, 'text');
            app.MMNfreqEditField.Editable = 'off';
            app.DeconvolutionmethodDropDownLabel = uilabel(app.UIFigure);
            app.DeconvolutionmethodDropDownLabel.Text = 'Deconvolution method';
            app.DeconvolutionmethodDropDown = uidropdown(app.UIFigure);
            app.DeconvolutionmethodDropDown.Items = {};
            app.DeconvolutionmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @DeconvolutionmethodDropDownValueChanged, true);
            app.DeconvolutionmethodDropDown.Value = {};
            app.SmoothCheckBox = uicheckbox(app.UIFigure);
            app.SmoothCheckBox.ValueChangedFcn = createCallbackFcn(app, @SmoothCheckBoxValueChanged, true);
            app.SmoothCheckBox.Text = 'Smooth';
            app.SmoothsigmamsEditFieldLabel = uilabel(app.UIFigure);
            app.SmoothsigmamsEditFieldLabel.Text = 'Smooth sigma (ms)';
            app.SmoothsigmamsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SmoothsigmamsEditField.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dset_viewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end